classdef UpperSOA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Panel                  matlab.ui.container.Panel          % Parameters
        LabelNumericEditField2 matlab.ui.control.Label            % Signal ...
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField  matlab.ui.control.Label            % Ascendi...
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        Button                 matlab.ui.control.Button           % START
        LabelNumericEditField3 matlab.ui.control.Label            % Thresho...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelDropDown          matlab.ui.control.Label            % Modulat...
        DropDown               matlab.ui.control.DropDown         % Gaussia...
        LabelEditField         matlab.ui.control.Label            % Partici...
        EditField              matlab.ui.control.EditField        % QUAN
        Label                  matlab.ui.control.Label            % Method ...
        Label2                 matlab.ui.control.Label            % Descend...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label3                 matlab.ui.control.Label            % Initial...
        NumericEditField5      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label4                 matlab.ui.control.Label            % Step Si...
        NumericEditField6      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label5                 matlab.ui.control.Label            % Reversa...
        NumericEditField7      matlab.ui.control.NumericEditField % [-Inf Inf]
        Button4                matlab.ui.control.Button           % STOP
        Button2                matlab.ui.control.Button           % Yes
        Button3                matlab.ui.control.Button           % No
        Label6                 matlab.ui.control.Label            % Is the ...
    end


    properties (Access = private)
        interleaver % Interleaver object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            devID = findMOTU24;
            if ~playrec('isInitialised');
                playrec('init', 44100, devID, -1, 24)
            end
        end

        % Button button pushed function
        function start(app)
            init_soa_asc = app.NumericEditField.Value;
            init_soa_desc = app.NumericEditField4.Value;
            init_step = app.NumericEditField5.Value;
            step = app.NumericEditField6.Value;
            reversals = app.NumericEditField7.Value;
            duration = app.NumericEditField2.Value;
            threshold = app.NumericEditField3.Value;
            modulation = lower(app.DropDown.Value);
            id = app.EditField.Value;
            try
                intensities = generate_intensities(id, threshold);
                app.interleaver = InterleaveManager(intensities, duration, init_soa_desc, init_soa_asc, modulation, init_step, step, reversals);
            catch  Exception
                msgbox(['Error starting: ', getReport(Exception,'basic')]);
            end
            %disp(['Participant ID: ',id,' Modulation: ',modulation,' SOA: ', num2str(init_soa), ' Duration: ', num2str(duration), ' Threshold: ', num2str(threshold)]);
        end

        % Button4 button pushed function
        function stop(app)
            if playrec('isInitialised')
                playrec('reset')
            end
        end

        % Button2 button pushed function
        function Yes(app)
            
            performed = app.interleaver.perform_trial(-1);
        end

        % Button3 button pushed function
        function No(app)
           
            performed = app.interleaver.perform_trial(1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Parameters';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Signal duration (ms)';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField2.Value = 400;
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Ascending Initial SOA (ms)';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.Value = 575;
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @start);
            app.Button.Text = 'START';
            app.LabelNumericEditField3 = uilabel(app.Panel);
            app.LabelNumericEditField3.Text = 'Threshold at 300 Hz (dB)';
            app.NumericEditField3 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField3.Value = -50;
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Modulation Type';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Gaussian', 'Linear', 'Exponential'};
            app.DropDown.Value = 'Gaussian';
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Participant ID';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Value = 'QUAN';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Method Parameters:';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Descending Initial SOA (ms)';
            app.NumericEditField4 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField4.Value = 575;
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Initial Step Size (ms)';
            app.NumericEditField5 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField5.Value = 16;
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Step Size (ms)';
            app.NumericEditField6 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField6.Value = 4;
            app.Label5 = uilabel(app.Panel);
            app.Label5.Text = 'Reversals Per Method';
            app.NumericEditField7 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField7.Value = 6;
            app.Button4 = uibutton(app.Panel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @stop);
            app.Button4.Text = 'STOP';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Yes);
            app.Button2.Text = 'Yes';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @No);
            app.Button3.Text = 'No';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Is the signal discrete?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UpperSOA()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end